classdef SerialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        GridLayout2       matlab.ui.container.GridLayout
        StartButton       matlab.ui.control.Button
        CleargraphButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Serial port object
        pointsCount
        dataLine
        serialBytesPerLine
        plotPointsPerUpdate
        bytesToRead
    end
    
    methods (Access = private)
        
        function readSerialData(app,src,~)
            app.s.UserData = read(src,app.bytesToRead,"string");
            data = str2double(strsplit(strtrim(erase(app.s.UserData,{char(0)}))));    
            %T = data(1:2:end);
            %P = data(2:2:end);
            %deltaH = data(3:3:end);
            deltaH = data;
            n = app.pointsCount + app.plotPointsPerUpdate - 1;
            addpoints(app.dataLine,app.pointsCount:n,deltaH);
            app.pointsCount = n + 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM3",19200);
            app.serialBytesPerLine = 10;
            app.plotPointsPerUpdate = 2;
            app.bytesToRead = app.plotPointsPerUpdate*app.serialBytesPerLine;
            app.pointsCount = 0;
            app.dataLine = animatedline(app.UIAxes);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            switch app.StartButton.Text
                case 'Start'
                    app.StartButton.Text = 'Stop';
                    flush(app.s);
                    %configureCallback(app.s,"byte",app.plotBytesPerUpdate,@app.readSerialData);
                    configureCallback(app.s,"byte",app.bytesToRead,@app.readSerialData);
                case 'Stop'
                    app.StartButton.Text = 'Start';
                    configureCallback(app.s,"off");
            end
        end

        % Button pushed function: CleargraphButton
        function CleargraphButtonPushed(app, event)
            clearpoints(app.dataLine);
            app.pointsCount = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CleargraphButton = uibutton(app.GridLayout2, 'push');
            app.CleargraphButton.ButtonPushedFcn = createCallbackFcn(app, @CleargraphButtonPushed, true);
            app.CleargraphButton.Text = 'Clear graph';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end