classdef HC08App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        ReceivedvalueEditField       matlab.ui.control.NumericEditField
        ReceivedvalueEditFieldLabel  matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        SendvalueEditField           matlab.ui.control.NumericEditField
        SendvalueEditFieldLabel      matlab.ui.control.Label
        GridLayout2                  matlab.ui.container.GridLayout
        SendData_Button              matlab.ui.control.Button
        Connect_Button               matlab.ui.control.Button
    end

    
    properties (Access = private)
        HC08 %BLE object
        HC08_Charact %BLE characteristic object
        %HC08connection_Listener %
    end
    
    methods (Access = private)
        
        function saveCharacteristicData(app,src)
            [data,timestamp] = read(src,'oldest');
            app.ReceivedvalueEditField.Value = data;
        end
        
%         function HC08_connectionChanged(app)
%             if (app.HC08.Connected == false)
%                 app.HC08 = '';
%                 app.Connect_Button.Text = 'Connect HC08';
%                 app.SendData_Button.Enable = 'off';
%             end
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SendData_Button.Enable = 'off';
        end

        % Button pushed function: Connect_Button
        function Connect_ButtonPushed(app, event)
            if strcmp(app.Connect_Button.Text, 'Connect HC08')
                try
                    app.HC08 = ble('HC-08');
                    app.Connect_Button.Text = 'Disconnect';
                    app.HC08_Charact = characteristic(app.HC08,'FFE0','FFE1');
                    app.HC08_Charact.DataAvailableFcn = @(obj,event)app.saveCharacteristicData(obj);
                    subscribe(app.HC08_Charact);
                    app.SendData_Button.Enable = 'on';
%                     app.HC08connection_Listener = addlistener(app.HC08.Connected,'PostSet',@app.HC08_connectionChanged);
                catch ME
                    switch ME.identifier
                    end
                end                
            else
                app.HC08 = '';
                app.Connect_Button.Text = 'Connect HC08';
                app.SendData_Button.Enable = 'off';
            end

        end

        % Button pushed function: SendData_Button
        function SendData_ButtonPushed(app, event)
            write(app.HC08_Charact,app.SendvalueEditField.Value,"withoutresponse");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Connect_Button = uibutton(app.GridLayout2, 'push');
            app.Connect_Button.ButtonPushedFcn = createCallbackFcn(app, @Connect_ButtonPushed, true);
            app.Connect_Button.Text = 'Connect HC08';
            app.SendData_Button = uibutton(app.GridLayout2, 'push');
            app.SendData_Button.ButtonPushedFcn = createCallbackFcn(app, @SendData_ButtonPushed, true);
            app.SendData_Button.Text = 'Send data';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout3);
            app.SendvalueEditFieldLabel = uilabel(app.Panel);
            app.SendvalueEditFieldLabel.Text = 'Send value:';
            app.SendvalueEditField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.GridLayout3);
            app.ReceivedvalueEditFieldLabel = uilabel(app.Panel_2);
            app.ReceivedvalueEditFieldLabel.Text = 'Received value';
            app.ReceivedvalueEditField = uieditfield(app.Panel_2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HC08App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end