classdef appTimer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        AddvalueButton    matlab.ui.control.Button
        EditFieldLabel    matlab.ui.control.Label
        Valor1            matlab.ui.control.NumericEditField
        EditField2Label   matlab.ui.control.Label
        Valor2            matlab.ui.control.NumericEditField
        StarttimerButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        tm
    end
    
    methods (Access = private)
        
        function timerISR(app,obj,event)
            app.Valor2.Value = app.Valor2.Value+1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tm = timer("BusyMode","drop","ExecutionMode","fixedRate","Period",0.1,"StartDelay",0,"TasksToExecute",100,"TimerFcn",@app.timerISR);
        end

        % Button pushed function: AddvalueButton
        function AddvalueButtonPushed(app, event)
            app.Valor1.Value = app.Valor1.Value+1;
        end

        % Button pushed function: StarttimerButton
        function StarttimerButtonPushed(app, event)
            start(app.tm);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddvalueButton = uibutton(app.UIFigure, 'push');
            app.AddvalueButton.ButtonPushedFcn = createCallbackFcn(app, @AddvalueButtonPushed, true);
            app.AddvalueButton.Text = 'Add value';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.Valor1 = uieditfield(app.UIFigure, 'numeric');
            app.Valor1.Editable = 'off';
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.Valor2 = uieditfield(app.UIFigure, 'numeric');
            app.Valor2.Editable = 'off';
            app.StarttimerButton = uibutton(app.UIFigure, 'push');
            app.StarttimerButton.ButtonPushedFcn = createCallbackFcn(app, @StarttimerButtonPushed, true);
            app.StarttimerButton.Text = 'Start timer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTimer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end