classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        F3AAcquisitionSystemRUIFigure  matlab.ui.Figure
        GeneralMenu                    matlab.ui.container.Menu
        ConnectdeviceMenu              matlab.ui.container.Menu
        ConfiguredeviceMenu            matlab.ui.container.Menu
        CalibratesensorsMenu           matlab.ui.container.Menu
        ErasedevicememoryMenu          matlab.ui.container.Menu
        LoadflightdataMenu             matlab.ui.container.Menu
        LoadfromdevicememoryMenu       matlab.ui.container.Menu
        LoadfromcomputerfileMenu       matlab.ui.container.Menu
        SaveflightdataMenu             matlab.ui.container.Menu
        FlightTrackMenu                matlab.ui.container.Menu
        SelectflighttrackMenu          matlab.ui.container.Menu
        AddnewflighttrackMenu          matlab.ui.container.Menu
        ManageflighttracksMenu         matlab.ui.container.Menu
        FlightViewerMenu               matlab.ui.container.Menu
        ViewsMenu                      matlab.ui.container.Menu
        AllViewsMenu                   matlab.ui.container.Menu
        FrontalviewonlyMenu            matlab.ui.container.Menu
        LateralviewonlyMenu            matlab.ui.container.Menu
        TopviewonlyMenu                matlab.ui.container.Menu
        DviewonlyMenu                  matlab.ui.container.Menu
        MainaircraftytrajectoryMenu    matlab.ui.container.Menu
        NotrajectorydisplayMenu        matlab.ui.container.Menu
        ShowaircrafttrailMenu          matlab.ui.container.Menu
        ShowpasttrajectoryMenu         matlab.ui.container.Menu
        ShowfulltrajectoryMenu         matlab.ui.container.Menu
        ManageloadedflightsMenu        matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        ConnectSwitch                  matlab.ui.container.toolbar.ToggleTool
        CalibrateSensorsButton         matlab.ui.container.toolbar.PushTool
        EraseDevMemoryButton           matlab.ui.container.toolbar.PushTool
        LoadDataDeviceButton           matlab.ui.container.toolbar.PushTool
        LoadDataComputerButton         matlab.ui.container.toolbar.PushTool
        SaveFlightButton               matlab.ui.container.toolbar.PushTool
        FrontalViewButton              matlab.ui.container.toolbar.PushTool
        LateralViewButton              matlab.ui.container.toolbar.PushTool
        TopViewButton                  matlab.ui.container.toolbar.PushTool
        DViewButton                    matlab.ui.container.toolbar.PushTool
        AllViewButton                  matlab.ui.container.toolbar.PushTool
        GlobalGridLayout               matlab.ui.container.GridLayout
        PlaybackControlsPanel          matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        StopPlaybackButton             matlab.ui.control.StateButton
        RepeatButton                   matlab.ui.control.StateButton
        PlaybackSlider                 matlab.ui.control.Slider
        FlightInstantEditField         matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        PlaybackDropDownLabel          matlab.ui.control.Label
        PlaybackDropDown               matlab.ui.control.DropDown
        NextFrameButton                matlab.ui.control.Button
        PlayPlaybackButton             matlab.ui.control.Button
        PrevFrameButton                matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        TrajectoryPanel                matlab.ui.container.Panel
        ViewersGridLayout              matlab.ui.container.GridLayout
        TridViewUIAxes                 matlab.ui.control.UIAxes
        TopViewUIAxes                  matlab.ui.control.UIAxes
        LateralViewUIAxes              matlab.ui.control.UIAxes
        FrontalViewUIAxes              matlab.ui.control.UIAxes
        GridLayout8                    matlab.ui.container.GridLayout
        OrientationviewPanel           matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        OrientationViewerUIAxes        matlab.ui.control.UIAxes
        InstantdataPanel               matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        InstantDataTextArea            matlab.ui.control.TextArea
        DevicePropertiesPanel          matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        DeviceInfoTextArea             matlab.ui.control.TextArea
        Label_5                        matlab.ui.control.Label
    end

    % Global variables
    properties (Access = public)
        GraphsLinkPos %variable that stores linked properties between the 4 axes viewers
        GraphsLinkOri 
        w % waitbar
        StateFSM; EventFSMQueue % queue of events
        HC06 = struct('Obj','','Cnt',0); %Cnt counts number of 4096 bytes packets already transmited
        DeviceInfo = struct('Name','','DataAvailable',false,'SectorCount4K',[],'PacketsCount',[],'nSamples',[]); 
        RawSensorData %holds raw sensor data comming from HC06
        SensorData = struct('nSamplesMax',0,'timeMaxFs',[],'fsIMU',0,'fsAlt',0,'fsGPS',0,...
            'accelData',[],'gyroData',[],'magnetoData',[],'altData',[],'gpsPosData',[],'gpsVelData',[],'refPoints',[]); %holds sensor data
        flightTrackCoord %holds the coordinates of the flight track: center, left and right
        estPos %holds result of estimated position points from Kalman filter
        estOri %holds result of estimated orientation from Kalman filter
        estOriRPY %same as estOri but in deg
        estVel %holds result of estimated velocity from Kalman filter
        bodyFrame %holds coordinate frame axes for every frame
        planePatch %holds plane patch for every frame
    end

    % HC06 commands constants
    properties (Constant)
        BLE_CMD_GetDevInfo = 'HC06-0'
        BLE_CMD_SetDevInfo = 'HC06-1'
        BLE_CMD_CalibSensors = 'HC06-2'
        BLE_CMD_EraseMemory = 'HC06-3'
        BLE_CMD_ReadMemory = 'HC06-4'
    end
    
    % Player properties
    properties (Access = public)
        FPS = 20;
        ratePlotDiv
        rateCtrl
        currentFrame
        playpause %state of push button
    end
    
    % FSM function
    methods (Access = private)
        
        % Process events and updates FSM state
        function FSM(app)
            eventFSM = processEvent(app.EventFSMQueue);
            switch app.StateFSM
                case 'Idle'
                    switch eventFSM
                        case 'ConnectButtonPressed'
                            if strcmp(app.ConnectdeviceMenu.Text,'Connect device') %if the bluetooth was disconnected
                                app.w = waitbar(0.1, 'Searching for devices...');
                                try % try to connect to bluetooth device
                                    app.HC06.Obj = bluetooth("HC-06");
                                    waitbar(0.8, app.w,'Device connected');
                                    app.ConnectSwitch.State = 'on';
                                    app.ConnectSwitch.Icon = 'Icons\Disconnect.png'; 
                                    app.ConnectdeviceMenu.Text = 'Disconnect device';
                                    app.ErasedevicememoryMenu.Enable = 'on';
                                    app.EraseDevMemoryButton.Enable = 'on';
                                    configureCallback(app.HC06.Obj,"byte",57,@(obj,event)app.readHC06Data(obj));
                                    app.StateFSM = 'ReceivingHC06_DevData';
                                    waitbar(1, app.w,'Connection succesful');
                                    pause(3);
                                    close(app.w);
                                    write(app.HC06.Obj,app.BLE_CMD_GetDevInfo);
                                catch ME
                                    switch ME.identifier
                                        case {'MATLAB:bluetooth:bluetooth:invalidIdentifierValue'}
                                            text = 'First pair the device with computer bluetooth';     
                                        case {'MATLAB:bluetooth:bluetoothlist:winBluetoothNotPoweredOn'}
                                            text = 'Computer bluetooth is OFF. Turn it ON!';
                                        case {'MATLAB:bluetooth:bluetooth:failedConnect'}
                                            text = 'Device is OFF. Turn it ON!';
                                    end
                                    app.ConnectSwitch.State = 'off';
                                    waitbar(1, app.w,text);
                                    pause(3);
                                    close(app.w);   
                                end
                            else % if the BLE was connected
                                app.HC06.Obj = '';
                                app.ConnectSwitch.State = 'off';
                                app.ConnectSwitch.Icon = 'Icons\Connect.png'; 
                                app.ConnectdeviceMenu.Text = 'Connect device';    
                                app.CalibratesensorsMenu.Enable = 'off';
                                app.CalibrateSensorsButton.Enable = 'off';
                                app.ErasedevicememoryMenu.Enable = 'off';
                                app.EraseDevMemoryButton.Enable = 'off'; 
                                clearDeviceInfoBox(app);
                            end
                        case 'LoadDeviceDataButtonPressed'
                            app.HC06.Cnt = 0;
                            configureCallback(app.HC06.Obj,"byte",4096,@(obj,event)app.readHC06Data(obj));
                            app.w = waitbar(0, 'Reading sensors data...');
                            app.StateFSM = 'ReceivingHC06_SensorData';
                            write(app.HC06.Obj,app.BLE_CMD_ReadMemory);
                        case 'CalibrateButtonPressed'
                            write(app.HC06.Obj,app.BLE_CMD_CalibSensors);
                        case 'EraseButtonPressed'
                            write(app.HC06.Obj,app.BLE_CMD_EraseMemory);
                            app.w = waitbar(0.1,'Erasing device memory...');
                            for i = 1:30
                                pause(1); %takes 30 seconds as maximum
                                waitbar(i/30,app.w,'Erasing device memory...');
                            end
                            close(app.w); 
                            configureCallback(app.HC06.Obj,"byte",57,@(obj,event)app.readHC06Data(obj));
                            app.StateFSM = 'ReceivingHC06_DevData';
                            write(app.HC06.Obj,app.BLE_CMD_GetDevInfo);
                        case 'Play'
                            app.StateFSM = 'Playing';
                            playPlayer(app);
                        case 'Stop'
                            moveToFramePlayer(app,1);  
                            app.StopPlaybackButton.Value = 0;
                        case 'NextFrame'
                            nextFramePlayer(app);
                        case 'PrevFrame'
                            prevFramePlayer(app);
                        case 'LoadComputerData'
                            [file,path] = uigetfile('.\FlightSessions\*.mat');
                            loadFlightDataComputer(app,path,file);
                        otherwise                        
                    end
                case 'ReceivingHC06_DevData'
                    switch eventFSM
                        case 'HC06_Rx_Finished'
                            app.StateFSM = 'Idle';
                    end
                case 'ReceivingHC06_SensorData'
                    switch eventFSM
                        case 'HC06_Rx_Finished'
                            app.StateFSM = 'Idle';
                    end 
                case 'Playing'
                    switch eventFSM
                        case 'Pause'
                            app.StateFSM = 'Idle';
                        case 'Stop'
                            app.StateFSM = 'Idle';
                        case 'NextFrame'
                            app.StateFSM = 'Idle';
                        case 'PrevFrame'
                            app.StateFSM = 'Idle';
                        otherwise
                    end
            end
        end

    end
    
    % Data related functions
    methods (Access = public)
        
        % Load default app options
        function loadDefaultUserOptions(app) %#ok<MANU> 
            
        end 
        
        % Stores flight data from device
        function readHC06Data(app,src)
            toc
            tic
            switch(app.StateFSM)
                case 'ReceivingHC06_DevData' 
                    RawDeviceInfo = read(src,src.BytesAvailableFcnCount);
                    app.DeviceInfo.Name = char(RawDeviceInfo(4:53));
                    if RawDeviceInfo(1) %if there is no data in the device memory
                        app.DeviceInfo.DataAvailable = false;
                        app.LoadfromdevicememoryMenu.Enable = 'off';
                        app.LoadDataDeviceButton.Enable = 'off';
                        app.DeviceInfo.SectorCount4K = 0;
                        app.RawSensorData = 0;
                        app.DeviceInfo.nSamples = 0;
                    else
                        app.DeviceInfo.DataAvailable = true;
                        app.LoadfromdevicememoryMenu.Enable = 'on';
                        app.LoadDataDeviceButton.Enable = 'on';
                        app.DeviceInfo.SectorCount4K = uint16(typecast(cast(RawDeviceInfo(2:3),'uint8'),'uint16'));
                        app.DeviceInfo.PacketsCount = app.DeviceInfo.SectorCount4K*8; % each sector has 8 packets of 512 bytes
                        app.RawSensorData = zeros(4096*app.DeviceInfo.SectorCount4K,1,'uint8');
                        app.DeviceInfo.nSamples = uint32(typecast(cast(RawDeviceInfo(54:57),'uint8'),'uint32'));
                    end
                    updateDeviceInfoBox(app);
                    addEvent(app.EventFSMQueue,'HC06_Rx_Finished');
                    FSM(app);                            
                case 'ReceivingHC06_SensorData'
                    app.RawSensorData((app.HC06.Cnt*4096+1):((app.HC06.Cnt+1)*4096)) = read(src,src.BytesAvailableFcnCount);
                    app.HC06.Cnt = app.HC06.Cnt + 1; 
                    if (app.HC06.Cnt == app.DeviceInfo.SectorCount4K)
                        close(app.w)
                        configureCallback(app.HC06.Obj,"byte",57,@(obj,event)app.readHC06Data(obj));
                        addEvent(app.EventFSMQueue,'HC06_Rx_Finished');
                        FSM(app);                         
                    else
                        waitbar(double(app.HC06.Cnt)/double(app.DeviceInfo.SectorCount4K), app.w,'Reading sensors data...');
                    end
            end
        end
   
        % Loads sensor data from matfile
        function loadFlightDataComputer(app,path,file)
            if ischar(file)
                load(strcat(path,file),'measurements');
                app.SensorData = measurements;
                clear measurements;                
            end
            [app.estPos,app.estOri,app.estVel] = runKalmanEstimation(app.SensorData);
            [r, p, y] = quat2angle(compact(app.estOri));
            app.estOriRPY(:,1) = rad2deg(r);
            app.estOriRPY(:,2) = rad2deg(p);
            app.estOriRPY(:,3) = rad2deg(y);
            [app.bodyFrame] = updateBodyFrame(app.estOri);
            app.planePatch = updatePlanePatch(app.estOri);
            app.OrientationViewerUIAxes.Children(2).Faces = app.planePatch.faces; %object one is the lightcam
            drawFullTrajectory(app);
            initPlayer(app); % initializes player
            showPlayer(app); % shows player bar
        end
        
    end
    
    % Player functions
    methods (Access = private)
        
        function playPlayer(app)
            app.PlayPlaybackButton.Icon = 'Pause.png';
            if (app.currentFrame == app.SensorData.nSamplesMax) %if users push play button and the plane is on the last frame
                app.currentFrame = 1;
            end
            for ii = app.currentFrame:app.ratePlotDiv:app.SensorData.nSamplesMax
                moveToFramePlayer(app,ii);
                if strcmp(app.StateFSM,'Idle') %if pause or stop button has been pressed
                    app.PlayPlaybackButton.Icon = 'Play.png'; 
                    if (app.StopPlaybackButton.Value) %if the stop button has been pressed
                        moveToFramePlayer(app,1)
                        app.StopPlaybackButton.Value = 0;
                    end
                    break
                end
                drawnow;
                waitfor(app.rateCtrl);
            end
            if strcmp(app.StateFSM,'Playing') %if end the for loop without any pause
                app.PlayPlaybackButton.Icon = 'Play.png'; 
                app.StateFSM = 'Idle';
            end
        end
        
        function stopPlayer(app) %#ok<MANU> 
            
        end
            
        function nextFramePlayer(app)
            if ~((app.currentFrame+1)>app.SensorData.nSamplesMax)
                moveToFramePlayer(app,app.currentFrame+1); 
            else
                moveToFramePlayer(app,1);
            end            
        end
        
        function prevFramePlayer(app)
            if ~((app.currentFrame-1)==0)
                moveToFramePlayer(app,app.currentFrame-1); 
            else
                moveToFramePlayer(app,app.SensorData.nSamplesMax);
            end                
        end
        
        function changePlaybackSpeedPlayer(app)
            
        end
        
        function moveToFramePlayer(app,frame)
            app.currentFrame = frame;
            app.PlaybackSlider.Value = (app.currentFrame-1)/app.SensorData.fsIMU;
            updateViewersFrame(app);
            updateInstantData(app);
        end
        
        function initPlayer(app)
            moveToFramePlayer(app,1);
            app.PlaybackSlider.Limits = [0, app.SensorData.timeMaxFs(end)];
            app.PlaybackSlider.Value = 0;
            app.ratePlotDiv = round(app.SensorData.fsIMU/app.FPS);
            app.rateCtrl = rateControl(app.SensorData.fsIMU/app.ratePlotDiv);
            pause(3);
        end
        
        function hidePlayer(app)
            app.GlobalGridLayout.RowHeight{2} = 0; % Hides player bar
        end
        
        function showPlayer(app)
            app.GlobalGridLayout.RowHeight{2} = 45; % Displays player bar
        end
    end
    
    % Graphics & interface related functions
    methods (Access = private)
        
        % Initialize interface objects status and plots (user default)
        function initAppInterface(app)
            % PLAYER BAR
            hidePlayer(app); 
            
            % POSITION VIEWERS
            % Link some axis properties
            app.GraphsLinkPos = linkprop([app.TridViewUIAxes,app.FrontalViewUIAxes,app.LateralViewUIAxes,app.TopViewUIAxes], ...
                {'XLim','YLim','ZLim','DataAspectRatio','XDir','YDir','ZDir','XGrid','YGrid','ZGrid'});   
            app.GraphsLinkOri = linkprop([app.TridViewUIAxes,app.OrientationViewerUIAxes],'View');
            % Plot ref points and lines on the 3d viewer
            drawFlightTrack(app.TridViewUIAxes);
            % Initialize trajectory line & plane coordinate system
            quiver3(app.TridViewUIAxes,0,0,0,0,0,0,'Color','r','LineWidth',1.5,'MaxHeadSize',1); %x axis arrow
            quiver3(app.TridViewUIAxes,0,0,0,0,0,0,'Color','g','LineWidth',1.5,'MaxHeadSize',1); %y axis arrow
            quiver3(app.TridViewUIAxes,0,0,0,0,0,0,'Color','b','LineWidth',1.5,'MaxHeadSize',1); %z axis arrow
            plot3(app.TridViewUIAxes,NaN,NaN,NaN,'Marker','o','MarkerSize',4,'MarkerEdgeColor','k','MarkerFaceColor','k'); %plane point
            plot3(app.TridViewUIAxes,NaN,NaN,NaN,'Color','k'); %trajectory    
            % Copy the graphical objects to the other graph viewers
            copyobj(app.TridViewUIAxes.Children,app.FrontalViewUIAxes);
            copyobj(app.TridViewUIAxes.Children,app.LateralViewUIAxes);
            copyobj(app.TridViewUIAxes.Children,app.TopViewUIAxes);
            % Set views
            view(app.TridViewUIAxes,30,20);
            view(app.FrontalViewUIAxes,0,0);
            view(app.LateralViewUIAxes,270,0);
            view(app.TopViewUIAxes,0,90);
            setViewersMode(app,'3DView'); % Set viewer default mode
            
            % ORIENTATION VIEWER
            dummyS.Faces = [1,2,3];
            dummyS.Vertices = [0,0,0;0,0,0;0,0,0];
            app.OrientationViewerUIAxes.DataAspectRatio = [1 1 1];
            grid(app.OrientationViewerUIAxes,"on");
            app.OrientationViewerUIAxes.XLim = [-1400,1400];
            app.OrientationViewerUIAxes.YLim = [-1400,1400];
            app.OrientationViewerUIAxes.ZLim = [-1400,1400];
            app.OrientationViewerUIAxes.XTickLabel = [];
            app.OrientationViewerUIAxes.YTickLabel = [];
            app.OrientationViewerUIAxes.ZTickLabel = [];
            app.OrientationViewerUIAxes.XDir = 'reverse';
            app.OrientationViewerUIAxes.ZDir = 'reverse';
            app.OrientationViewerUIAxes.XLabel.String = 'x North';
            app.OrientationViewerUIAxes.YLabel.String = 'y East';
            app.OrientationViewerUIAxes.ZLabel.String = 'z Down';
            patch(app.OrientationViewerUIAxes,dummyS,'Facecolor',[0 0.5451 0.8196],'EdgeColor','none',...
                'FaceLighting','gouraud','AmbientStrength', 0.22); % dummy aircraft patch children            
            camlight(app.OrientationViewerUIAxes,'headlight');
            material(app.OrientationViewerUIAxes.Children(1),'shiny'); %object one is the light object
            %TEXT BOXES
            clearInstantData(app);
            clearDeviceInfoBox(app)
        end

        % Set viewers mode
        function setViewersMode(app,mode)
            switch mode
                case 'AllViews'
                    app.ViewersGridLayout.RowHeight = {'1x','1x'};
                    app.ViewersGridLayout.ColumnWidth = {'1x','1x'};
                    app.AllViewsMenu.Checked = 'on';
                    app.FrontalviewonlyMenu.Checked = 'off';
                    app.LateralviewonlyMenu.Checked = 'off';
                    app.TopviewonlyMenu.Checked = 'off';
                    app.DviewonlyMenu.Checked = 'off';
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','on');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','on');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','on');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','on');
                case 'FrontalView'
                    app.ViewersGridLayout.RowHeight = {'1x',0};
                    app.ViewersGridLayout.ColumnWidth = {'1x',0};
                    app.AllViewsMenu.Checked = 'off';
                    app.FrontalviewonlyMenu.Checked = 'on';
                    app.LateralviewonlyMenu.Checked = 'off';
                    app.TopviewonlyMenu.Checked = 'off';
                    app.DviewonlyMenu.Checked = 'off';      
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','off');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','off');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','off');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','on');                  
                case 'LateralView'
                    app.ViewersGridLayout.RowHeight = {'1x',0};
                    app.ViewersGridLayout.ColumnWidth = {0,'1x'};
                    app.AllViewsMenu.Checked = 'off';
                    app.FrontalviewonlyMenu.Checked = 'off';
                    app.LateralviewonlyMenu.Checked = 'on';
                    app.TopviewonlyMenu.Checked = 'off';
                    app.DviewonlyMenu.Checked = 'off';
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','off');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','on');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','off');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','off');                   
                case 'TopView'
                    app.ViewersGridLayout.RowHeight = {0,'1x'};
                    app.ViewersGridLayout.ColumnWidth = {'1x',0};
                    app.AllViewsMenu.Checked = 'off';
                    app.FrontalviewonlyMenu.Checked = 'off';
                    app.LateralviewonlyMenu.Checked = 'off';
                    app.TopviewonlyMenu.Checked = 'on';
                    app.DviewonlyMenu.Checked = 'off';
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','off');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','off');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','on');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','off');                   
                case '3DView'
                    app.ViewersGridLayout.RowHeight = {0,'1x'};
                    app.ViewersGridLayout.ColumnWidth = {0,'1x'};
                    app.AllViewsMenu.Checked = 'off';
                    app.FrontalviewonlyMenu.Checked = 'off';
                    app.LateralviewonlyMenu.Checked = 'off';
                    app.TopviewonlyMenu.Checked = 'off';
                    app.DviewonlyMenu.Checked = 'on';
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','on');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','off');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','off');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','off');                   
                otherwise
            end            
        end
        
        % Updates aircraft position in all viewers
        function updateViewersFrame(app)
            % Orientation viewer
            app.OrientationViewerUIAxes.Children(2).Vertices = rotatepoint(app.estOri(app.currentFrame),app.planePatch.vertices);
            
            % 3D viewer
            app.TridViewUIAxes.Children(2).XData = app.estPos(app.currentFrame,1); %punto cg
            app.TridViewUIAxes.Children(2).YData = app.estPos(app.currentFrame,2); %punto cg
            app.TridViewUIAxes.Children(2).ZData = app.estPos(app.currentFrame,3); %punto cg
            app.TridViewUIAxes.Children(3).XData = app.estPos(app.currentFrame,1); %eje z
            app.TridViewUIAxes.Children(3).YData = app.estPos(app.currentFrame,2); %eje z
            app.TridViewUIAxes.Children(3).ZData = app.estPos(app.currentFrame,3); %eje z
            app.TridViewUIAxes.Children(3).UData = app.bodyFrame(3,1,app.currentFrame); %eje z
            app.TridViewUIAxes.Children(3).VData = app.bodyFrame(3,2,app.currentFrame); %eje z
            app.TridViewUIAxes.Children(3).WData = app.bodyFrame(3,3,app.currentFrame); %eje z
            app.TridViewUIAxes.Children(4).XData = app.estPos(app.currentFrame,1); %eje y
            app.TridViewUIAxes.Children(4).YData = app.estPos(app.currentFrame,2); %eje y
            app.TridViewUIAxes.Children(4).ZData = app.estPos(app.currentFrame,3); %eje y
            app.TridViewUIAxes.Children(4).UData = app.bodyFrame(2,1,app.currentFrame); %eje y
            app.TridViewUIAxes.Children(4).VData = app.bodyFrame(2,2,app.currentFrame); %eje y
            app.TridViewUIAxes.Children(4).WData = app.bodyFrame(2,3,app.currentFrame); %eje y
            app.TridViewUIAxes.Children(5).XData = app.estPos(app.currentFrame,1); %eje x
            app.TridViewUIAxes.Children(5).YData = app.estPos(app.currentFrame,2); %eje x
            app.TridViewUIAxes.Children(5).ZData = app.estPos(app.currentFrame,3); %eje x
            app.TridViewUIAxes.Children(5).UData = app.bodyFrame(1,1,app.currentFrame); %eje x
            app.TridViewUIAxes.Children(5).VData = app.bodyFrame(1,2,app.currentFrame); %eje x
            app.TridViewUIAxes.Children(5).WData = app.bodyFrame(1,3,app.currentFrame); %eje x          
        end
        
        % Draws full trajectory on the axes
        function drawFullTrajectory(app)
            app.TridViewUIAxes.Children(1).XData = app.estPos(:,1);
            app.TridViewUIAxes.Children(1).YData = app.estPos(:,2);
            app.TridViewUIAxes.Children(1).ZData = app.estPos(:,3);
        end
    
        % Updates instant data box
        function updateInstantData(app)
            textVal = {'------------------------ POSITION [m] -----------------------';...
                       strcat('x:  ',num2str(app.estPos(app.currentFrame,1)));...
                       strcat('y:  ',num2str(app.estPos(app.currentFrame,2)));...
                       strcat('z:  ',num2str(app.estPos(app.currentFrame,3)));...
                       '----------------------- VELOCITY [m/s] ----------------------';...
                       strcat('x:  ',num2str(app.estVel(app.currentFrame,1)));...
                       strcat('y:  ',num2str(app.estVel(app.currentFrame,2)));...
                       strcat('z:  ',num2str(app.estVel(app.currentFrame,3)));...
                       'Climb rate: - ';
                       'Ground speed: - ';
                       '--------------------- ORIENTATION [deg] -------------------';...
                       strcat('Roll:  ',num2str(app.estOriRPY(app.currentFrame,1)));...
                       strcat('Pitch:  ',num2str(app.estOriRPY(app.currentFrame,2)));...
                       strcat('Yaw:  ',num2str(app.estOriRPY(app.currentFrame,3)))};
            app.InstantDataTextArea.Value = textVal;
        end
        
        % Clear instant data box
        function clearInstantData(app)
            textVal = {'------------------------ POSITION [m] -----------------------';...
                       'x: - ';...
                       'y: - ';...
                       'z: - ';...
                       '----------------------- VELOCITY [m/s] ----------------------';...
                       'x: - ';...
                       'y: - ';...
                       'z: - ';...
                       'Climb rate: - ';
                       'Ground speed: - ';
                       '--------------------- ORIENTATION [deg] -------------------';...
                       'Roll: - ';...
                       'Pitch: - ';...
                       'Yaw: - '};
            app.InstantDataTextArea.Value = textVal;
        end
        
        % Update device information textbox
        function updateDeviceInfoBox(app)
            if (app.DeviceInfo.DataAvailable)
                textVal = {strcat('Device from: ',app.DeviceInfo.Name,  ' - Connected');...
                    '';...
                    'Available flight data:';...
                    strcat(num2str(app.DeviceInfo.SectorCount4K), ' 4KB sectors', ' (', num2str(app.DeviceInfo.PacketsCount), ' packets of 512 bytes)');...
                    strcat(num2str(double(app.DeviceInfo.PacketsCount)*512), ' bytes');...
                    strcat(num2str(app.DeviceInfo.nSamples), ' sampling periods');...
                    '';...
                    strcat('Flight duration: ', num2str(double(app.DeviceInfo.nSamples)*0.01),' s (', num2str(double(app.DeviceInfo.nSamples)*0.01/60,'%.2f'), ' min)');...
                    strcat('Aprox flight duration: ~ ', num2str(double(app.DeviceInfo.PacketsCount)*512/132*0.01),' s (', num2str(double(app.DeviceInfo.PacketsCount)*512/132*0.01/60,'%.2f'), ' min)')};
            else
                textVal = {strcat('Device from: ', app.DeviceInfo.Name, ' - Connected');...
                    'No flight data available on this device'};                
            end
            app.DeviceInfoTextArea.Value = textVal;
        end
        
        % Clear device information textbox
        function clearDeviceInfoBox(app)
            app.DeviceInfoTextArea.Value = 'Device disconnected';
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadDefaultUserOptions(app);
            initAppInterface(app); 
            % FSM initialization
            app.StateFSM = 'Idle';
            app.EventFSMQueue = eventQueue(10); % event queue creation. Size 10 elements
        end

        % Callback function: AllViewButton, AllViewsMenu, 
        % DViewButton, DviewonlyMenu, FrontalViewButton, 
        % FrontalviewonlyMenu, LateralViewButton, 
        % LateralviewonlyMenu, TopViewButton, TopviewonlyMenu
        function ViewMenuSelected(app, event)
            setViewersMode(app,event.Source.Tag);
        end

        % Clicked callback: LoadDataComputerButton
        function LoadDataComputerButtonPushed(app, event)
            addEvent(app.EventFSMQueue,'LoadComputerData');
            FSM(app);
        end

        % Button pushed function: PlayPlaybackButton
        function PlayPlaybackButtonPushed(app, event)
            if strcmp(app.StateFSM,'Playing')
                addEvent(app.EventFSMQueue,'Pause');
            elseif strcmp(app.StateFSM,'Idle')
                addEvent(app.EventFSMQueue,'Play');
            end
            FSM(app);
        end

        % Close request function: F3AAcquisitionSystemRUIFigure
        function F3AAcquisitionSystemRUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Callback function: ConnectSwitch, ConnectdeviceMenu
        function ConnectSwitchClicked(app, event)
            addEvent(app.EventFSMQueue,'ConnectButtonPressed');
            FSM(app);            
        end

        % Callback function: CalibrateSensorsButton, 
        % CalibratesensorsMenu
        function CalibrateSensorsButtonClicked(app, event)
            addEvent(app.EventFSMQueue,'CalibrateButtonPressed');
            FSM(app);              
        end

        % Callback function: EraseDevMemoryButton, 
        % ErasedevicememoryMenu
        function EraseDevMemoryButtonClicked(app, event)
            addEvent(app.EventFSMQueue,'EraseButtonPressed');
            FSM(app);              
        end

        % Menu selected function: ConfiguredeviceMenu
        function ConfiguredeviceMenuSelected(app, event)
            
        end

        % Callback function: LoadDataDeviceButton, 
        % LoadfromdevicememoryMenu
        function LoadDataDeviceButtonClicked(app, event)
            addEvent(app.EventFSMQueue,'LoadDeviceDataButtonPressed');
            FSM(app); 
        end

        % Value changed function: StopPlaybackButton
        function StopPlaybackButtonPushed(app, event)
            addEvent(app.EventFSMQueue,'Stop');
            FSM(app);
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            addEvent(app.EventFSMQueue,'NextFrame');
            FSM(app);            
        end

        % Button pushed function: PrevFrameButton
        function PrevFrameButtonPushed(app, event)
            addEvent(app.EventFSMQueue,'PrevFrame');
            FSM(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.F3AAcquisitionSystemRUIFigure = uifigure('Visible', 'off');
            app.F3AAcquisitionSystemRUIFigure.Name = 'F3A Acquisition System (R)';
            app.F3AAcquisitionSystemRUIFigure.Icon = 'Aircraft.png';
            app.F3AAcquisitionSystemRUIFigure.CloseRequestFcn = createCallbackFcn(app, @F3AAcquisitionSystemRUIFigureCloseRequest, true);
            app.GeneralMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.GeneralMenu.Text = 'General';
            app.ConnectdeviceMenu = uimenu(app.GeneralMenu);
            app.ConnectdeviceMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectSwitchClicked, true);
            app.ConnectdeviceMenu.Text = 'Connect device';
            app.ConfiguredeviceMenu = uimenu(app.GeneralMenu);
            app.ConfiguredeviceMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfiguredeviceMenuSelected, true);
            app.ConfiguredeviceMenu.Enable = 'off';
            app.ConfiguredeviceMenu.Text = 'Configure device';
            app.CalibratesensorsMenu = uimenu(app.GeneralMenu);
            app.CalibratesensorsMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrateSensorsButtonClicked, true);
            app.CalibratesensorsMenu.Enable = 'off';
            app.CalibratesensorsMenu.Text = 'Calibrate sensors';
            app.ErasedevicememoryMenu = uimenu(app.GeneralMenu);
            app.ErasedevicememoryMenu.MenuSelectedFcn = createCallbackFcn(app, @EraseDevMemoryButtonClicked, true);
            app.ErasedevicememoryMenu.Enable = 'off';
            app.ErasedevicememoryMenu.Text = 'Erase device memory';
            app.LoadflightdataMenu = uimenu(app.GeneralMenu);
            app.LoadflightdataMenu.Text = 'Load flight data';
            app.LoadfromdevicememoryMenu = uimenu(app.LoadflightdataMenu);
            app.LoadfromdevicememoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataDeviceButtonClicked, true);
            app.LoadfromdevicememoryMenu.Enable = 'off';
            app.LoadfromdevicememoryMenu.Text = 'Load from device memory';
            app.LoadfromcomputerfileMenu = uimenu(app.LoadflightdataMenu);
            app.LoadfromcomputerfileMenu.Text = 'Load from computer file';
            app.SaveflightdataMenu = uimenu(app.GeneralMenu);
            app.SaveflightdataMenu.Enable = 'off';
            app.SaveflightdataMenu.Text = 'Save flight data...';
            app.FlightTrackMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.FlightTrackMenu.Text = 'Flight track';
            app.SelectflighttrackMenu = uimenu(app.FlightTrackMenu);
            app.SelectflighttrackMenu.Text = 'Select flight track';
            app.AddnewflighttrackMenu = uimenu(app.FlightTrackMenu);
            app.AddnewflighttrackMenu.Text = 'Add new flight track';
            app.ManageflighttracksMenu = uimenu(app.FlightTrackMenu);
            app.ManageflighttracksMenu.Text = 'Manage flight tracks';
            app.FlightViewerMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.FlightViewerMenu.Text = 'Flight viewer';
            app.ViewsMenu = uimenu(app.FlightViewerMenu);
            app.ViewsMenu.Text = 'Views';
            app.AllViewsMenu = uimenu(app.ViewsMenu);
            app.AllViewsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.AllViewsMenu.Checked = 'on';
            app.AllViewsMenu.Text = '4 views';
            app.AllViewsMenu.Tag = 'AllViews';
            app.FrontalviewonlyMenu = uimenu(app.ViewsMenu);
            app.FrontalviewonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.FrontalviewonlyMenu.Text = 'Frontal view only';
            app.FrontalviewonlyMenu.Tag = 'FrontalView';
            app.LateralviewonlyMenu = uimenu(app.ViewsMenu);
            app.LateralviewonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.LateralviewonlyMenu.Text = 'Lateral view only';
            app.LateralviewonlyMenu.Tag = 'LateralView';
            app.TopviewonlyMenu = uimenu(app.ViewsMenu);
            app.TopviewonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.TopviewonlyMenu.Text = 'Top view only';
            app.TopviewonlyMenu.Tag = 'TopView';
            app.DviewonlyMenu = uimenu(app.ViewsMenu);
            app.DviewonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.DviewonlyMenu.Text = '3D view only';
            app.DviewonlyMenu.Tag = '3DView';
            app.MainaircraftytrajectoryMenu = uimenu(app.FlightViewerMenu);
            app.MainaircraftytrajectoryMenu.Text = 'Main aircrafty trajectory';
            app.NotrajectorydisplayMenu = uimenu(app.MainaircraftytrajectoryMenu);
            app.NotrajectorydisplayMenu.Text = 'No trajectory display';
            app.ShowaircrafttrailMenu = uimenu(app.MainaircraftytrajectoryMenu);
            app.ShowaircrafttrailMenu.Checked = 'on';
            app.ShowaircrafttrailMenu.Text = 'Show aircraft trail';
            app.ShowpasttrajectoryMenu = uimenu(app.MainaircraftytrajectoryMenu);
            app.ShowpasttrajectoryMenu.Text = 'Show past trajectory';
            app.ShowfulltrajectoryMenu = uimenu(app.MainaircraftytrajectoryMenu);
            app.ShowfulltrajectoryMenu.Text = 'Show full trajectory';
            app.ManageloadedflightsMenu = uimenu(app.FlightViewerMenu);
            app.ManageloadedflightsMenu.Enable = 'off';
            app.ManageloadedflightsMenu.Text = 'Manage loaded flights';
            app.HelpMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.F3AAcquisitionSystemRUIFigure);
            app.ConnectSwitch = uitoggletool(app.Toolbar);
            app.ConnectSwitch.ClickedCallback = createCallbackFcn(app, @ConnectSwitchClicked, true);
            app.ConnectSwitch.Icon = 'Connect.png';
            app.CalibrateSensorsButton = uipushtool(app.Toolbar);
            app.CalibrateSensorsButton.Tooltip = {'Calibrate sensors'};
            app.CalibrateSensorsButton.ClickedCallback = createCallbackFcn(app, @CalibrateSensorsButtonClicked, true);
            app.CalibrateSensorsButton.Enable = 'off';
            app.CalibrateSensorsButton.Icon = 'Calibrate.png';
            app.EraseDevMemoryButton = uipushtool(app.Toolbar);
            app.EraseDevMemoryButton.Tooltip = {'Erase device memory'};
            app.EraseDevMemoryButton.ClickedCallback = createCallbackFcn(app, @EraseDevMemoryButtonClicked, true);
            app.EraseDevMemoryButton.Enable = 'off';
            app.EraseDevMemoryButton.Icon = 'Bin.png';
            app.LoadDataDeviceButton = uipushtool(app.Toolbar);
            app.LoadDataDeviceButton.Tooltip = {'Load last flight data from device memory'};
            app.LoadDataDeviceButton.ClickedCallback = createCallbackFcn(app, @LoadDataDeviceButtonClicked, true);
            app.LoadDataDeviceButton.Enable = 'off';
            app.LoadDataDeviceButton.Icon = 'Load data from device.png';
            app.LoadDataDeviceButton.Separator = 'on';
            app.LoadDataComputerButton = uipushtool(app.Toolbar);
            app.LoadDataComputerButton.Tooltip = {'Load flight data from computer file'};
            app.LoadDataComputerButton.ClickedCallback = createCallbackFcn(app, @LoadDataComputerButtonPushed, true);
            app.LoadDataComputerButton.Icon = 'Load data from file.png';
            app.SaveFlightButton = uipushtool(app.Toolbar);
            app.SaveFlightButton.Tooltip = {'Save flight recording to computer'};
            app.SaveFlightButton.Enable = 'off';
            app.SaveFlightButton.Icon = 'Save.png';
            app.FrontalViewButton = uipushtool(app.Toolbar);
            app.FrontalViewButton.Tag = 'FrontalView';
            app.FrontalViewButton.Tooltip = {'Front view only'};
            app.FrontalViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.FrontalViewButton.Icon = 'FrontView.png';
            app.FrontalViewButton.Separator = 'on';
            app.LateralViewButton = uipushtool(app.Toolbar);
            app.LateralViewButton.Tag = 'LateralView';
            app.LateralViewButton.Tooltip = {'Lateral view only'};
            app.LateralViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.LateralViewButton.Icon = 'LateralView.png';
            app.TopViewButton = uipushtool(app.Toolbar);
            app.TopViewButton.Tag = 'TopView';
            app.TopViewButton.Tooltip = {'Top view only'};
            app.TopViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.TopViewButton.Icon = 'TopView.png';
            app.DViewButton = uipushtool(app.Toolbar);
            app.DViewButton.Tag = '3DView';
            app.DViewButton.Tooltip = {'3D view only'};
            app.DViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.DViewButton.Icon = '3DView.png';
            app.AllViewButton = uipushtool(app.Toolbar);
            app.AllViewButton.Tag = 'AllViews';
            app.AllViewButton.Tooltip = {'All views'};
            app.AllViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.AllViewButton.Icon = 'AllViews.png';
            app.GlobalGridLayout = uigridlayout(app.F3AAcquisitionSystemRUIFigure);
            app.GridLayout2 = uigridlayout(app.GlobalGridLayout);
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.DevicePropertiesPanel = uipanel(app.GridLayout8);
            app.DevicePropertiesPanel.Title = 'Device information';
            app.GridLayout9 = uigridlayout(app.DevicePropertiesPanel);
            app.Label_5 = uilabel(app.GridLayout9);
            app.Label_5.Text = '';
            app.DeviceInfoTextArea = uitextarea(app.GridLayout9);
            app.DeviceInfoTextArea.Editable = 'off';
            app.InstantdataPanel = uipanel(app.GridLayout8);
            app.InstantdataPanel.Title = 'Instant data';
            app.GridLayout12 = uigridlayout(app.InstantdataPanel);
            app.InstantDataTextArea = uitextarea(app.GridLayout12);
            app.InstantDataTextArea.Editable = 'off';
            app.OrientationviewPanel = uipanel(app.GridLayout8);
            app.OrientationviewPanel.Title = 'Orientation view';
            app.GridLayout11 = uigridlayout(app.OrientationviewPanel);
            app.OrientationViewerUIAxes = uiaxes(app.GridLayout11);
            xlabel(app.OrientationViewerUIAxes, 'X')
            ylabel(app.OrientationViewerUIAxes, 'Y')
            zlabel(app.OrientationViewerUIAxes, 'Z')
            app.TrajectoryPanel = uipanel(app.GridLayout2);
            app.TrajectoryPanel.Title = 'Flight viewer';
            app.ViewersGridLayout = uigridlayout(app.TrajectoryPanel);
            app.FrontalViewUIAxes = uiaxes(app.ViewersGridLayout);
            title(app.FrontalViewUIAxes, 'Frontal')
            xlabel(app.FrontalViewUIAxes, 'X')
            ylabel(app.FrontalViewUIAxes, 'Y')
            zlabel(app.FrontalViewUIAxes, 'Z')
            app.LateralViewUIAxes = uiaxes(app.ViewersGridLayout);
            title(app.LateralViewUIAxes, 'Lateral')
            xlabel(app.LateralViewUIAxes, 'X')
            ylabel(app.LateralViewUIAxes, 'Y')
            zlabel(app.LateralViewUIAxes, 'Z')
            app.TopViewUIAxes = uiaxes(app.ViewersGridLayout);
            title(app.TopViewUIAxes, 'Top')
            xlabel(app.TopViewUIAxes, 'X')
            ylabel(app.TopViewUIAxes, 'Y')
            zlabel(app.TopViewUIAxes, 'Z')
            app.TridViewUIAxes = uiaxes(app.ViewersGridLayout);
            title(app.TridViewUIAxes, '3D')
            xlabel(app.TridViewUIAxes, 'X')
            ylabel(app.TridViewUIAxes, 'Y')
            zlabel(app.TridViewUIAxes, 'Z')
            app.PlaybackControlsPanel = uipanel(app.GlobalGridLayout);
            app.GridLayout7 = uigridlayout(app.PlaybackControlsPanel);
            app.PrevFrameButton = uibutton(app.GridLayout7, 'push');
            app.PrevFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PrevFrameButtonPushed, true);
            app.PrevFrameButton.Icon = 'Previous.png';
            app.PrevFrameButton.Tooltip = {'Previous frame'};
            app.PrevFrameButton.Text = '';
            app.PlayPlaybackButton = uibutton(app.GridLayout7, 'push');
            app.PlayPlaybackButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPlaybackButtonPushed, true);
            app.PlayPlaybackButton.Icon = 'Play.png';
            app.PlayPlaybackButton.Text = '';
            app.NextFrameButton = uibutton(app.GridLayout7, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Icon = 'Next.png';
            app.NextFrameButton.Tooltip = {'Next frame'};
            app.NextFrameButton.Text = '';
            app.Panel = uipanel(app.GridLayout7);
            app.PlaybackDropDown = uidropdown(app.Panel);
            app.PlaybackDropDown.Items = {'0.25x', '0.5x', '0.75x', '1x', '1.25x', '1.5x', '1.75x', '2x'};
            app.PlaybackDropDown.ItemsData = {'0.25', '0.5', '0.75', '1', '1.25', '1.5', '1.75', '2'};
            app.PlaybackDropDown.Value = '1';
            app.PlaybackDropDownLabel = uilabel(app.Panel);
            app.PlaybackDropDownLabel.Text = 'Playback';
            app.FlightInstantEditField = uieditfield(app.GridLayout7, 'numeric');
            app.PlaybackSlider = uislider(app.GridLayout7);
            app.RepeatButton = uibutton(app.GridLayout7, 'state');
            app.RepeatButton.Tooltip = {'Repeat in loop'};
            app.RepeatButton.Icon = 'Repeat.png';
            app.RepeatButton.Text = '';
            app.StopPlaybackButton = uibutton(app.GridLayout7, 'state');
            app.StopPlaybackButton.ValueChangedFcn = createCallbackFcn(app, @StopPlaybackButtonPushed, true);
            app.StopPlaybackButton.Icon = 'Stop.png';
            app.StopPlaybackButton.Text = '';
            % Show the figure after all components are created
            app.F3AAcquisitionSystemRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.F3AAcquisitionSystemRUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.F3AAcquisitionSystemRUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.F3AAcquisitionSystemRUIFigure)
        end
    end
end